classdef hrd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        DropDownLabel  matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        Button         matlab.ui.control.Button
        Label          matlab.ui.control.Label
    end

    
    properties (Access = private)
        rt  = "横刀立马";% Description
        
    end
    
    properties (Access = public)
        board; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function chooseGame(app, event)
            %value = app.DropDown.Value;
            if app.DropDown.Value == "随机"
               app.rt = "随机";
            elseif app.DropDown.Value == "横刀立马"
                app.rt = "横刀立马";
            elseif app.DropDown.Value == "兵分三路"
                app.rt = "兵分三路";
            elseif app.DropDown.Value == "齐头并进"
                app.rt = "齐头并进";
            end
        end

        % Button pushed function: Button
        function startGame(app, event)
            game = Game();
            game.playGame(app.rt,repmat("0",5,4));
            app.board = game.board;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = {'初始局面'; ''};
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'横刀立马', '兵分三路', '随机', '齐头并进'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @chooseGame, true);
            app.DropDown.Value = '兵分三路';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @startGame, true);
            app.Button.Text = '开始';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '华容道';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hrd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end